classdef BME_Signal_Analysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        uipanel2                       matlab.ui.container.Panel
        text16                         matlab.ui.control.Label
        text17                         matlab.ui.control.Label
        text18                         matlab.ui.control.Label
        text19                         matlab.ui.control.Label
        test_oscilloscope              matlab.ui.control.Button
        text4_2                        matlab.ui.control.Label
        show_pos_box                   matlab.ui.control.CheckBox
        text4_3                        matlab.ui.control.Label
        text4_4                        matlab.ui.control.Label
        uipanel8                       matlab.ui.container.Panel
        finalize_data                  matlab.ui.control.Button
        checkbox8                      matlab.ui.control.CheckBox
        checkbox9                      matlab.ui.control.CheckBox
        SelectedPulsesListBoxLabel     matlab.ui.control.Label
        SelectedPulsesListBox          matlab.ui.control.ListBox
        SelectedResponsesListBoxLabel  matlab.ui.control.Label
        SelectedResponsesListBox       matlab.ui.control.ListBox
        show_neg_box                   matlab.ui.control.CheckBox
        show_delay_box                 matlab.ui.control.CheckBox
        text6                          matlab.ui.control.Label
        text7                          matlab.ui.control.Label
        text8                          matlab.ui.control.Label
        text9                          matlab.ui.control.Label
        y_upper_text                   matlab.ui.control.EditField
        y_lower_text                   matlab.ui.control.EditField
        x_lower_text                   matlab.ui.control.EditField
        x_upper_text                   matlab.ui.control.EditField
        uipanel4                       matlab.ui.container.Panel
        text24                         matlab.ui.control.Label
        text26                         matlab.ui.control.Label
        text10                         matlab.ui.control.Label
        text11                         matlab.ui.control.Label
        test_cell_resp                 matlab.ui.control.Button
        text3                          matlab.ui.control.Label
        upper_bound                    matlab.ui.control.EditField
        text4                          matlab.ui.control.Label
        pulse_y_max                    matlab.ui.control.EditField
        pulse_y_min                    matlab.ui.control.EditField
        Pulse_Time_Min                 matlab.ui.control.EditField
        Pulse_Time_Max                 matlab.ui.control.EditField
        text38                         matlab.ui.control.Label
        text39                         matlab.ui.control.Label
        text40                         matlab.ui.control.Label
        text41                         matlab.ui.control.Label
        lower_bound                    matlab.ui.control.EditField
        ShowPeaksearchboundsCheckBox   matlab.ui.control.CheckBox
        ShowprenormalizedtracesCheckBox  matlab.ui.control.CheckBox
        osc_sampling_rate              matlab.ui.control.EditField
        text14                         matlab.ui.control.Label
        text26_2                       matlab.ui.control.Label
        text15                         matlab.ui.control.Label
        cell_sampling_rate             matlab.ui.control.EditField
        text26_3                       matlab.ui.control.Label
    end

    
    properties (Access = public)
         ItemsBox_Scope % Description
         ItemsBox_Flur
         Fluorescence_Sheet = array2table(zeros(5), 'VariableNames', {'Time (s)','None Selected','Mean', 'STD', 'SEM'});
         Scope_Sheet = array2table(zeros(5), 'VariableNames', {'Time (s)','None Selected','Mean', 'STD', 'SEM'});
    end
    
    methods (Access = private)
        function frames_average_b4_peak_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to frames_average_b4_peak (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pulse_time_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to pulse_time (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function time_threshold_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to time_threshold (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Analyze_Files_OpeningFcn(app, varargin)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Analyze_Files (see VARARGIN)
            
            % Choose default command line output for Analyze_Files
            handles.output = hObject;
            handles.text35.String = date;
            % Update handles structure
            guidata(hObject, handles)
            movegui(hObject, 'onscreen')    %moves fig to the screen
        end

        % Button pushed function: test_oscilloscope
        function test_oscilloscopeButtonPushed(app, event)
             app.Scope_Sheet
            [app.Scope_Sheet, app.ItemsBox_Scope] = Investigate_Scopes(str2double(app.x_lower_text.Value), ...
                str2double(app.x_upper_text.Value), str2double(app.y_lower_text.Value), ...
                str2double(app.y_upper_text.Value), (app.show_pos_box.Value), ...
                (app.show_neg_box.Value), (app.show_delay_box.Value),...
                str2double(app.osc_sampling_rate.Value));
            
            app.Scope_Sheet
            app.SelectedPulsesListBox.Items = (string(app.ItemsBox_Scope));
            app.SelectedPulsesListBox.Value = (string(app.ItemsBox_Scope));
        end

        % Button pushed function: test_cell_resp
        function test_cell_respButtonPushed(app, event)

            [app.Fluorescence_Sheet, app.ItemsBox_Flur] = Investigate_Fluorescence(str2double(app.Pulse_Time_Min.Value),...
                str2double(app.Pulse_Time_Max.Value), str2double(app.pulse_y_min.Value),...
                str2double(app.pulse_y_max.Value), str2double(app.cell_sampling_rate.Value),...
                str2double(app.lower_bound.Value), str2double(app.upper_bound.Value), app.ShowPeaksearchboundsCheckBox.Value, ...
                app.ShowprenormalizedtracesCheckBox.Value);
            
            app.SelectedResponsesListBox.Items = string(app.ItemsBox_Flur);
            app.SelectedResponsesListBox.Value = string(app.ItemsBox_Flur);
        end

        % Button pushed function: finalize_data
        function finalize_dataButtonPushed(app, event)
            filename = strcat(date, '_full','.xlsx');
            [file_name,path_name] = uiputfile(filename);
            filename = strcat(path_name,file_name);
           if exist(filename, 'file');
            delete(filename);  %overwrite file completely.
           end
           
            if app.checkbox9.Value
                 SelectedVariables = app.SelectedPulsesListBox.Value;
                 SelectedTable = app.Scope_Sheet(:,['Time (s)',SelectedVariables, 'Mean', 'STD', 'SEM']);
                writetable(SelectedTable,filename,'Sheet','Oscilloscope')
 
           end
           if app.checkbox8.Value
                 SelectedVariables = app.SelectedResponsesListBox.Value;
                 SelectedTable = app.Fluorescence_Sheet(:,['Time (s)',SelectedVariables, 'Mean', 'STD', 'SEM']);
                writetable(SelectedTable,filename,'Sheet','Fluorescence')
           end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Analyze_Files';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Oscilloscope Output Setup';
            app.uipanel2.Tag = 'uipanel2';
            app.text16 = uilabel(app.uipanel2);
            app.text16.Tag = 'text16';
            app.text16.Text = 'kV';
            app.text17 = uilabel(app.uipanel2);
            app.text17.Tag = 'text17';
            app.text17.Text = 'kV';
            app.text18 = uilabel(app.uipanel2);
            app.text18.Tag = 'text18';
            app.text18.Text = 'ns';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Tag = 'text19';
            app.text19.Text = 'ns';
            app.test_oscilloscope = uibutton(app.uipanel2, 'push');
            app.test_oscilloscope.ButtonPushedFcn = createCallbackFcn(app, @test_oscilloscopeButtonPushed, true);
            app.test_oscilloscope.Tag = 'test_oscilloscope';
            app.test_oscilloscope.Text = 'Select Oscilloscope Files from Folder';
            app.text4_2 = uilabel(app.uipanel2);
            app.text4_2.Tag = 'text4';
            app.text4_2.Text = {''; '◯'};
            app.show_pos_box = uicheckbox(app.uipanel2);
            app.show_pos_box.Tag = 'show_pos_box';
            app.show_pos_box.Text = 'Show Positive Peak as';
            app.show_pos_box.Value = true;
            app.text4_3 = uilabel(app.uipanel2);
            app.text4_3.Tag = 'text4';
            app.text4_3.Text = {''; '◯'};
            app.text4_4 = uilabel(app.uipanel2);
            app.text4_4.Tag = 'text4';
            app.text4_4.Text = '⬤';
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = 'Export to Excel';
            app.uipanel8.Tag = 'uipanel8';
            app.finalize_data = uibutton(app.uipanel8, 'push');
            app.finalize_data.ButtonPushedFcn = createCallbackFcn(app, @finalize_dataButtonPushed, true);
            app.finalize_data.Tag = 'finalize_data';
            app.finalize_data.Text = 'Save Selected Files to Folder';
            app.checkbox8 = uicheckbox(app.uipanel8);
            app.checkbox8.Tag = 'checkbox8';
            app.checkbox8.Text = 'Include Fluorescence Files?';
            app.checkbox8.Value = true;
            app.checkbox9 = uicheckbox(app.uipanel8);
            app.checkbox9.Tag = 'checkbox9';
            app.checkbox9.Text = 'Include Oscilloscope Files?';
            app.checkbox9.Value = true;
            app.SelectedPulsesListBoxLabel = uilabel(app.uipanel8);
            app.SelectedPulsesListBoxLabel.Text = 'Selected Pulses';
            app.SelectedPulsesListBox = uilistbox(app.uipanel8);
            app.SelectedPulsesListBox.Items = {'None Selected'};
            app.SelectedPulsesListBox.Multiselect = 'on';
            app.SelectedPulsesListBox.Value = {'None Selected'};
            app.SelectedResponsesListBoxLabel = uilabel(app.uipanel8);
            app.SelectedResponsesListBoxLabel.Text = 'Selected Responses';
            app.SelectedResponsesListBox = uilistbox(app.uipanel8);
            app.SelectedResponsesListBox.Items = {'None Selected'};
            app.SelectedResponsesListBox.Multiselect = 'on';
            app.SelectedResponsesListBox.Value = {'None Selected'};
            app.show_neg_box = uicheckbox(app.figure1);
            app.show_neg_box.Tag = 'show_neg_box';
            app.show_neg_box.Text = 'Show Negative Peak as';
            app.show_neg_box.Value = true;
            app.show_delay_box = uicheckbox(app.figure1);
            app.show_delay_box.Tag = 'show_delay_box';
            app.show_delay_box.Text = 'Show 0V Crossover Interphase Interval as';
            app.show_delay_box.Value = true;
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Oscilloscope Y MAX:';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Oscilloscope Y MIN:';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Oscilloscope Time MIN:';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Oscilloscope Time MAX:';
            app.y_upper_text = uieditfield(app.figure1, 'text');
            app.y_upper_text.Tag = 'y_upper_text';
            app.y_upper_text.Value = '3';
            app.y_lower_text = uieditfield(app.figure1, 'text');
            app.y_lower_text.Tag = 'y_lower_text';
            app.y_lower_text.Value = '-3';
            app.x_lower_text = uieditfield(app.figure1, 'text');
            app.x_lower_text.Tag = 'x_lower_text';
            app.x_lower_text.Value = '-25';
            app.x_upper_text = uieditfield(app.figure1, 'text');
            app.x_upper_text.Tag = 'x_upper_text';
            app.x_upper_text.Value = '130';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Cell Output Setup';
            app.uipanel4.Tag = 'uipanel4';
            app.text24 = uilabel(app.uipanel4);
            app.text24.Tag = 'text24';
            app.text24.Text = 's';
            app.text26 = uilabel(app.uipanel4);
            app.text26.Tag = 'text26';
            app.text26.Text = 's';
            app.text10 = uilabel(app.uipanel4);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Pulse Y MAX:';
            app.text11 = uilabel(app.uipanel4);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Pulse Y MIN:';
            app.test_cell_resp = uibutton(app.uipanel4, 'push');
            app.test_cell_resp.ButtonPushedFcn = createCallbackFcn(app, @test_cell_respButtonPushed, true);
            app.test_cell_resp.Tag = 'test_cell_resp';
            app.test_cell_resp.Text = 'Select Fluorescence Files from Folder';
            app.text3 = uilabel(app.uipanel4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Upper Peak Search Bound:';
            app.upper_bound = uieditfield(app.uipanel4, 'text');
            app.upper_bound.Tag = 'upper_bound';
            app.upper_bound.Value = '16';
            app.text4 = uilabel(app.uipanel4);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Lower Peak Search Bound:';
            app.pulse_y_max = uieditfield(app.uipanel4, 'text');
            app.pulse_y_max.Tag = 'pulse_y_max';
            app.pulse_y_max.Value = '2.5';
            app.pulse_y_min = uieditfield(app.uipanel4, 'text');
            app.pulse_y_min.Tag = 'pulse_y_min';
            app.pulse_y_min.Value = '0.5';
            app.Pulse_Time_Min = uieditfield(app.uipanel4, 'text');
            app.Pulse_Time_Min.Tag = 'Pulse_Time_Min';
            app.Pulse_Time_Min.Value = '0';
            app.Pulse_Time_Max = uieditfield(app.uipanel4, 'text');
            app.Pulse_Time_Max.Tag = 'Pulse_Time_Max';
            app.Pulse_Time_Max.Value = '90';
            app.text38 = uilabel(app.uipanel4);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Pulse Time MAX:';
            app.text39 = uilabel(app.uipanel4);
            app.text39.Tag = 'text39';
            app.text39.Text = 'Pulse Time MIN:';
            app.text40 = uilabel(app.uipanel4);
            app.text40.Tag = 'text40';
            app.text40.Text = 's';
            app.text41 = uilabel(app.uipanel4);
            app.text41.Tag = 'text41';
            app.text41.Text = 's';
            app.lower_bound = uieditfield(app.uipanel4, 'text');
            app.lower_bound.Tag = 'lower_bound';
            app.lower_bound.Value = '13.5';
            app.ShowPeaksearchboundsCheckBox = uicheckbox(app.uipanel4);
            app.ShowPeaksearchboundsCheckBox.Text = 'Show Peak search bounds.';
            app.ShowPeaksearchboundsCheckBox.Value = true;
            app.ShowprenormalizedtracesCheckBox = uicheckbox(app.uipanel4);
            app.ShowprenormalizedtracesCheckBox.Text = 'Show pre-normalized traces.';
            app.ShowprenormalizedtracesCheckBox.Value = true;
            app.osc_sampling_rate = uieditfield(app.figure1, 'text');
            app.osc_sampling_rate.Tag = 'osc_sampling_rate';
            app.osc_sampling_rate.Value = '5';
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Oscilloscope Error Bar sampling rate:';
            app.text26_2 = uilabel(app.figure1);
            app.text26_2.Tag = 'text26';
            app.text26_2.Text = 'frames';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Cell Error Bar sampling rate:';
            app.cell_sampling_rate = uieditfield(app.figure1, 'text');
            app.cell_sampling_rate.Tag = 'cell_sampling_rate';
            app.cell_sampling_rate.Value = '25';
            app.text26_3 = uilabel(app.figure1);
            app.text26_3.Tag = 'text26';
            app.text26_3.Text = 'frames';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BME_Signal_Analysis_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Analyze_Files_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end